classdef soru3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Soru3UIFigure                  matlab.ui.Figure
        TemizleButton                  matlab.ui.control.Button
        YiTahminlerListBox             matlab.ui.control.ListBox
        YiTahminlerListBoxLabel        matlab.ui.control.Label
        ModelTahminDenkemiYiEditField  matlab.ui.control.EditField
        ModelTahminDenkemiYiEditFieldLabel  matlab.ui.control.Label
        HesaplaButton                  matlab.ui.control.Button
        ydeikeniEditField              matlab.ui.control.EditField
        ydeikeniEditFieldLabel         matlab.ui.control.Label
        xdeikeniEditField              matlab.ui.control.EditField
        xdeikeniEditFieldLabel         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HesaplaButton
        function HesaplaButtonPushed(app, event)
x = str2num(app.xdeikeniEditField.Value);
y = str2num(app.ydeikeniEditField.Value);

% Verilerin sayısı
n = length(x);

% Ortalama hesaplama (manuel)
x_sum = 0;
y_sum = 0;

for i = 1:n
    x_sum = x_sum + x(i);
    y_sum = y_sum + y(i);
end

x_mean = x_sum / n; % x'in ortalaması
y_mean = y_sum / n; % y'nin ortalaması

% Eğim (slope) hesaplama (manuel)
numerator = 0; % Pay
denominator = 0; % Payda

for i = 1:n
    numerator = numerator + (x(i) - x_mean) * (y(i) - y_mean);
    denominator = denominator + (x(i) - x_mean)^2;
end

slope = numerator / denominator; % Eğim hesaplama

% Kesim noktası (intercept) hesaplama (manuel)
intercept = y_mean - slope * x_mean; % Kesim noktası hesaplama

% Yeni y tahmin vektörü
y_tahmin = []; % Boş bir vektör oluştur

% y tahminlerini hesaplama
for i = 1:n
    y_tahmin(i) = intercept + slope * x(i); % Regresyon denklemi ile y'yi hesapla
end

% Regresyon denklemini yazi değişkenine atayın
yazi = sprintf('Yi = %.2f +  %.2fx\n', intercept, slope);

% yazi değişkenini app.ModelTahminDenkemiYiEditField.Value'ya aktarın
app.ModelTahminDenkemiYiEditField.Value = num2str(yazi);
% ytahmin değerlerini string cell array olarak dönüştür 
ytahmin_str = cellstr(num2str(y_tahmin'));
% ytahmin_str değerlerini app.YiTahminlerListBox.Items'a atayın
app.YiTahminlerListBox.Items = ytahmin_str;

% Veri noktalarını ve regresyon doğrusunu UIAxes üzerinde çizdirin
scatter(app.UIAxes, x, y, 'filled'); 
% Veri noktalarını çizdirin 
hold(app.UIAxes, 'on'); % Ek çizimler için UIAxes'i tutun 
plot(app.UIAxes, x, y_tahmin, '-r');
% Regresyon doğrusunu çizdirin 
hold(app.UIAxes, 'off'); 
% Ek çizimleri tamamlayın 
% Eksen etiketlerini ekleyin 
xlabel(app.UIAxes, 'Bağımsız Değişken (x)'); 
ylabel(app.UIAxes, 'Bağımlı Değişken (y)'); 
title(app.UIAxes, 'Regresyon Saçılım Grafiği');

        end

        % Button pushed function: TemizleButton
        function TemizleButtonPushed(app, event)
app.ydeikeniEditField.Value = "";
app.xdeikeniEditField.Value = "";
app.ModelTahminDenkemiYiEditField.Value = "";
app.YiTahminlerListBox.Items = "";
% UIAxes'i temizleyin 
cla(app.UIAxes);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Soru3UIFigure = uifigure('Visible', 'off');
            app.Soru3UIFigure.Name = 'Soru 3';
            app.Soru3UIFigure.Icon = fullfile(pathToMLAPP, 'bayrak.png');
            app.UIAxes = uiaxes(app.Soru3UIFigure);
            title(app.UIAxes, 'Grafik')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.xdeikeniEditFieldLabel = uilabel(app.Soru3UIFigure);
            app.xdeikeniEditFieldLabel.Text = 'x değişkeni';
            app.xdeikeniEditField = uieditfield(app.Soru3UIFigure, 'text');
            app.ydeikeniEditFieldLabel = uilabel(app.Soru3UIFigure);
            app.ydeikeniEditFieldLabel.Text = 'y değişkeni';
            app.ydeikeniEditField = uieditfield(app.Soru3UIFigure, 'text');
            app.HesaplaButton = uibutton(app.Soru3UIFigure, 'push');
            app.HesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButtonPushed, true);
            app.HesaplaButton.Text = 'Hesapla';
            app.ModelTahminDenkemiYiEditFieldLabel = uilabel(app.Soru3UIFigure);
            app.ModelTahminDenkemiYiEditFieldLabel.Text = 'Model Tahmin Denkemi Yi';
            app.ModelTahminDenkemiYiEditField = uieditfield(app.Soru3UIFigure, 'text');
            app.YiTahminlerListBoxLabel = uilabel(app.Soru3UIFigure);
            app.YiTahminlerListBoxLabel.Text = 'Yi Tahminler';
            app.YiTahminlerListBox = uilistbox(app.Soru3UIFigure);
            app.YiTahminlerListBox.Items = {};
            app.YiTahminlerListBox.Value = {};
            app.TemizleButton = uibutton(app.Soru3UIFigure, 'push');
            app.TemizleButton.ButtonPushedFcn = createCallbackFcn(app, @TemizleButtonPushed, true);
            app.TemizleButton.Text = 'Temizle';
            % Show the figure after all components are created
            app.Soru3UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = soru3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Soru3UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Soru3UIFigure)
        end
    end
end