classdef soru4 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Soru4UIFigure              matlab.ui.Figure
        SfrlaButton                matlab.ui.control.Button
        XdeerleriEditFieldLabel_6  matlab.ui.control.Label
        XdeerleriEditFieldLabel_5  matlab.ui.control.Label
        XdeerleriEditFieldLabel_4  matlab.ui.control.Label
        XdeerleriEditFieldLabel_3  matlab.ui.control.Label
        XdeerleriEditFieldLabel_2  matlab.ui.control.Label
        HesaplaButton              matlab.ui.control.Button
        XdeerleriEditField_6       matlab.ui.control.EditField
        XdeerleriEditField_5       matlab.ui.control.EditField
        XdeerleriEditField_4       matlab.ui.control.EditField
        XdeerleriEditField_3       matlab.ui.control.EditField
        XdeerleriEditField_2       matlab.ui.control.EditField
        XdeerleriEditField         matlab.ui.control.EditField
        XdeerleriEditFieldLabel    matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: HesaplaButton
        function HesaplaButtonPushed(app, event)
                x = str2num(app.XdeerleriEditField.Value);
                 % 1. Ortalama hesaplama
    n = length(x);
    toplam = 0;
    for i = 1:n
        toplam = toplam + x(i); % Elemanları toplar
    end
    ortalama = toplam / n; % Ortalamayı hesaplar

    % 2. Varyans hesaplama
    toplam2 = 0;
    for i = 1:n
        toplam2 = toplam2 + (x(i) - ortalama)^2; % Ortalamadan farkların kareleri
    end
    varyans = toplam2 / (n - 1); % Varyansı hesaplar (örnek varyansı)

    % 3. Sıralama (Bubble Sort)
    y = x; % Orijinal diziyi korumak için kopya oluştur
    for i = 1:n-1
        for j = i+1:n
            if y(j) < y(i) % Küçükten büyüğe sıralama
                temp = y(i);
                y(i) = y(j);
                y(j) = temp;
            end
        end
    end

    % 4. Medyan hesaplama
    modsayisi = n;
    while modsayisi < 0
        modsayisi = modsayisi + 2;
    end

    while modsayisi >= 2
        modsayisi = modsayisi - 2;
    end

    if modsayisi == 1
        medyan = y((n + 1) / 2); % Tek sayıda eleman varsa
    else
        medyan = (y(n / 2) + y(n / 2 + 1)) / 2; % Çift sayıda eleman varsa
    end

    % 5. Q1 ve Q3 hesaplama
    if modsayisi == 1
        % Tek sayıda eleman varsa, medyanın sol ve sağındaki diziler
        sol = y(1:(n - 1) / 2);
        sag = y((n + 1) / 2 + 1:end);
    else
        % Çift sayıda eleman varsa, medyanın sol ve sağındaki diziler
        sol = y(1:n / 2);
        sag = y((n / 2 + 1):end);
    end

    % Q1 hesaplama
    n_sol = length(sol);
    modsayisi_sol = n_sol;
    while modsayisi_sol < 0
        modsayisi_sol = modsayisi_sol + 2;
    end

    while modsayisi_sol >= 2
        modsayisi_sol = modsayisi_sol - 2;
    end

    if modsayisi_sol == 1
        q1 = sol((n_sol + 1) / 2); % Tek sayıda eleman varsa
    else
        q1 = (sol(n_sol / 2) + sol(n_sol / 2 + 1)) / 2; % Çift sayıda eleman varsa
    end

    % Q3 hesaplama
    n_sag = length(sag);
    modsayisi_sag = n_sag;
    while modsayisi_sag < 0
        modsayisi_sag = modsayisi_sag + 2;
    end

    while modsayisi_sag >= 2
        modsayisi_sag = modsayisi_sag - 2;
    end

    if modsayisi_sag == 1
        q3 = sag((n_sag + 1) / 2); % Tek sayıda eleman varsa
    else
        q3 = (sag(n_sag / 2) + sag(n_sag / 2 + 1)) / 2; % Çift sayıda eleman varsa
    end
app.XdeerleriEditField_2.Value=num2str(ortalama);
app.XdeerleriEditField_3.Value=num2str(varyans);
app.XdeerleriEditField_4.Value=num2str(q1);
app.XdeerleriEditField_5.Value=num2str(medyan);
app.XdeerleriEditField_6.Value=num2str(q3);


        end

        % Button pushed function: SfrlaButton
        function SfrlaButtonPushed(app, event)
app.XdeerleriEditField.Value="";
app.XdeerleriEditField_2.Value="";
app.XdeerleriEditField_3.Value="";
app.XdeerleriEditField_4.Value="";
app.XdeerleriEditField_5.Value="";
app.XdeerleriEditField_6.Value="";
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.Soru4UIFigure = uifigure('Visible', 'off');
            app.Soru4UIFigure.Name = 'Soru 4';
            app.Soru4UIFigure.Icon = fullfile(pathToMLAPP, 'bayrak.png');
            app.XdeerleriEditFieldLabel = uilabel(app.Soru4UIFigure);
            app.XdeerleriEditFieldLabel.Text = 'X değerleri';
            app.XdeerleriEditField = uieditfield(app.Soru4UIFigure, 'text');
            app.XdeerleriEditField_2 = uieditfield(app.Soru4UIFigure, 'text');
            app.XdeerleriEditField_3 = uieditfield(app.Soru4UIFigure, 'text');
            app.XdeerleriEditField_4 = uieditfield(app.Soru4UIFigure, 'text');
            app.XdeerleriEditField_5 = uieditfield(app.Soru4UIFigure, 'text');
            app.XdeerleriEditField_6 = uieditfield(app.Soru4UIFigure, 'text');
            app.HesaplaButton = uibutton(app.Soru4UIFigure, 'push');
            app.HesaplaButton.ButtonPushedFcn = createCallbackFcn(app, @HesaplaButtonPushed, true);
            app.HesaplaButton.Text = 'Hesapla';
            app.XdeerleriEditFieldLabel_2 = uilabel(app.Soru4UIFigure);
            app.XdeerleriEditFieldLabel_2.Text = 'Ortalama';
            app.XdeerleriEditFieldLabel_3 = uilabel(app.Soru4UIFigure);
            app.XdeerleriEditFieldLabel_3.Text = 'Varyans';
            app.XdeerleriEditFieldLabel_4 = uilabel(app.Soru4UIFigure);
            app.XdeerleriEditFieldLabel_4.Text = '1. Çeyreklik';
            app.XdeerleriEditFieldLabel_5 = uilabel(app.Soru4UIFigure);
            app.XdeerleriEditFieldLabel_5.Text = 'Medyan';
            app.XdeerleriEditFieldLabel_6 = uilabel(app.Soru4UIFigure);
            app.XdeerleriEditFieldLabel_6.Text = '3. Çeyreklik';
            app.SfrlaButton = uibutton(app.Soru4UIFigure, 'push');
            app.SfrlaButton.ButtonPushedFcn = createCallbackFcn(app, @SfrlaButtonPushed, true);
            app.SfrlaButton.Text = 'Sıfırla';
            % Show the figure after all components are created
            app.Soru4UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = soru4

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Soru4UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Soru4UIFigure)
        end
    end
end